classdef threeDPrinterGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        printerGUIFigure           matlab.ui.Figure
        FilamentPanel              matlab.ui.container.Panel
        ClearPrintBedButton        matlab.ui.control.Button
        Color3ColorPicker          matlab.ui.control.ColorPicker
        Color3ColorPickerLabel     matlab.ui.control.Label
        Color2ColorPicker          matlab.ui.control.ColorPicker
        Color2ColorPickerLabel     matlab.ui.control.Label
        Color1ColorPicker          matlab.ui.control.ColorPicker
        Color1ColorPickerLabel     matlab.ui.control.Label
        NozzleValueEditField       matlab.ui.control.NumericEditField
        NozzleValueEditFieldLabel  matlab.ui.control.Label
        nozslider                  matlab.ui.control.Slider
        joint3sliderLabel_2        matlab.ui.control.Label
        SavedPositionsPanel        matlab.ui.container.Panel
        pos3                       matlab.ui.control.Label
        Goto3Button                matlab.ui.control.Button
        Set3Button                 matlab.ui.control.Button
        pos2                       matlab.ui.control.Label
        Goto2Button                matlab.ui.control.Button
        Set2Button                 matlab.ui.control.Button
        pos1                       matlab.ui.control.Label
        Goto1Button                matlab.ui.control.Button
        Set1Button                 matlab.ui.control.Button
        HOMEButton                 matlab.ui.control.Button
        GOButton                   matlab.ui.control.Button
        YValueEditField            matlab.ui.control.NumericEditField
        YValueEditFieldLabel       matlab.ui.control.Label
        XValueEditField            matlab.ui.control.NumericEditField
        XValueEditFieldLabel       matlab.ui.control.Label
        ZValueEditField            matlab.ui.control.NumericEditField
        ZValueEditFieldLabel       matlab.ui.control.Label
        xslider                    matlab.ui.control.Slider
        joint2sliderLabel          matlab.ui.control.Label
        yslider                    matlab.ui.control.Slider
        joint3sliderLabel          matlab.ui.control.Label
        zslider                    matlab.ui.control.Slider
        joint1sliderLabel          matlab.ui.control.Label
        axes_arm                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link0Patch
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        jointAngles = [0,0,0,0];

        savedAngles1 = [0,0,0,0];
        savedAngles2 = [0,0,0,0];
        savedAngles3 = [0,0,0,0];

        lines;
        NozzlePositions;

        currentColor
        color1 = "";
        color2 = "";
        color3 = "";
        thickness = 1;
    end
    
    methods (Access = private)
        
        function updateArm(app)
            A1 = create_A_matrix(0, app.jointAngles(1), 0, 0);
            A2 = create_A_matrix(app.jointAngles(2), 0, 0, 0);
            A3 = create_A_matrix(app.jointAngles(3), 0, 0, -90);
            A4 = create_A_matrix(0, 0, 0, app.jointAngles(4));

            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            T0_4 = T0_3*A4;
            
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;
           
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');

            NozzlePos = T0_4(1:3, 4);
            if isempty(app.NozzlePositions)
                app.NozzlePositions = NozzlePos;
            end
            
            newLine = line(app.axes_arm, ...
                'XData', [app.NozzlePositions(1), NozzlePos(1)], ...
                'YData', [app.NozzlePositions(2), NozzlePos(2)], ...
                'ZData', [app.NozzlePositions(3), NozzlePos(3)], ...
                'Color', app.currentColor, ...
                'LineWidth', app.thickness);
                    
            app.lines{end+1} = newLine;
            
            % Update the previous position for the next step
            app.NozzlePositions = NozzlePos;
            
        end

        function animation(app, targets)
            startAngles = app.jointAngles;
            step=50;

            for i = 1:step
                app.jointAngles = startAngles + (targets - startAngles) * (i/step);

                app.zslider.Value = app.jointAngles(1);
                app.xslider.Value = app.jointAngles(2) + 80; 
                app.yslider.Value = app.jointAngles(3) * (-1) + 80;
                app.nozslider.Value = app.jointAngles(4);

                app.ZValueEditField.Value = app.jointAngles(1);
                app.XValueEditField.Value = app.jointAngles(2);
                app.YValueEditField.Value = app.jointAngles(3);
                app.NozzleValueEditField.Value = app.jointAngles(4);

                app.updateArm();
                drawnow; 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            app.color1 = app.Color1ColorPicker.Value;
            app.color2 = app.Color2ColorPicker.Value;
            app.color3 = app.Color3ColorPicker.Value;
            app.currentColor = app.color1;

            app.axes_arm.View = [25 25];
            app.axes_arm.Visible = 'off';

            
            app.link0Patch = makeStlLink("link0.stl", app.axes_arm, [.2 .2 .2]);
            reducepatch(app.link0Patch, 0.15); 
            set(app.link0Patch, 'FaceAlpha', 0.2); 
            
            app.link1Patch = makeStlLink("link1z.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("link2x.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("link3y (2).stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            
            app.link4Patch = makeStlLink("link4noz.stl", app.axes_arm, [255/255 165/255 0/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            app.updateArm();
        end

        % Value changing function: zslider
        function zsliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            %TODO: Also update the numeric arm
            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            target = [app.ZValueEditField.Value,app.XValueEditField.Value,app.YValueEditField.Value,app.NozzleValueEditField.Value];
            app.animation(target);
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            target = [0,0,0,0];
            app.animation(target);
        end

        % Value changing function: xslider
        function xsliderValueChanging(app, event)
            changingValue = event.Value-80;
            app.jointAngles(2) = changingValue;
            app.updateArm()
        end

        % Value changing function: yslider
        function ysliderValueChanging(app, event)
            changingValue = (event.Value-80)*(-1);
            app.jointAngles(3) = changingValue;
            app.updateArm()            
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles;
            app.pos1.Text = sprintf('[%.1f, %.1f, %.1f\n%.1f]', app.savedAngles1(1), app.savedAngles1(2), app.savedAngles1(3), app.savedAngles1(4));
        end

        % Button pushed function: Goto1Button
        function Goto1ButtonPushed(app, event)
            app.animation(app.savedAngles1);
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.savedAngles2 = app.jointAngles;
            app.pos2.Text = sprintf('[%.1f, %.1f, %.1f\n%.1f]', app.savedAngles2(1), app.savedAngles2(2), app.savedAngles2(3), app.savedAngles2(4));
        end

        % Button pushed function: Goto2Button
        function Goto2ButtonPushed(app, event)
            app.animation(app.savedAngles2);
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.savedAngles3 = app.jointAngles;
            app.pos3.Text = sprintf('[%.1f, %.1f, %.1f\n%.1f]', app.savedAngles3(1), app.savedAngles3(2), app.savedAngles3(3), app.savedAngles3(4));
        end

        % Button pushed function: Goto3Button
        function Goto3ButtonPushed(app, event)
            app.animation(app.savedAngles3);
        end

        % Value changing function: nozslider
        function nozsliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(4) = changingValue;
            if app.jointAngles(4) == 0 || app.jointAngles(4) == 360
                app.thickness = 1;
                app.currentColor = app.color1;
            elseif app.jointAngles(4) == 120
                app.thickness = 5;
                app.currentColor = app.color2;
            elseif app.jointAngles(4) == 240
                app.thickness = 10;
                app.currentColor = app.color3;
            end
            app.updateArm()
            
        end

        % Callback function: Color1ColorPicker
        function Color1ColorPickerValueChanged(app, event)
            value = app.Color1ColorPicker.Value;
            app.color1 = value;
        end

        % Callback function: Color2ColorPicker
        function Color2ColorPickerValueChanged(app, event)
            value = app.Color2ColorPicker.Value;
            app.color2 = value;
        end

        % Callback function: Color3ColorPicker
        function Color3ColorPickerValueChanged(app, event)
            value = app.Color3ColorPicker.Value;
            app.color3 = value;
        end

        % Button pushed function: ClearPrintBedButton
        function ClearPrintBedButtonPushed(app, event)
            if ~isempty(app.lines)
                delete([app.lines{:}]);
                app.lines = {};
                app.NozzlePositions = [];
                app.updateArm();        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.printerGUIFigure = uifigure('Visible', 'off');
            app.printerGUIFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.printerGUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.joint1sliderLabel = uilabel(app.printerGUIFigure);
            app.joint1sliderLabel.Text = 'Z-Axis';
            app.zslider = uislider(app.printerGUIFigure);
            app.zslider.Limits = [0 180];
            app.zslider.ValueChangingFcn = createCallbackFcn(app, @zsliderValueChanging, true);
            app.zslider.Step = 30;
            app.joint3sliderLabel = uilabel(app.printerGUIFigure);
            app.joint3sliderLabel.Text = 'Y-Axis';
            app.yslider = uislider(app.printerGUIFigure);
            app.yslider.Limits = [0 160];
            app.yslider.ValueChangingFcn = createCallbackFcn(app, @ysliderValueChanging, true);
            app.yslider.Step = 30;
            app.yslider.Value = 80;
            app.joint2sliderLabel = uilabel(app.printerGUIFigure);
            app.joint2sliderLabel.Text = 'X-Axis';
            app.xslider = uislider(app.printerGUIFigure);
            app.xslider.Limits = [0 160];
            app.xslider.ValueChangingFcn = createCallbackFcn(app, @xsliderValueChanging, true);
            app.xslider.Step = 30;
            app.xslider.Value = 80;
            app.ZValueEditFieldLabel = uilabel(app.printerGUIFigure);
            app.ZValueEditFieldLabel.Text = 'Z Value';
            app.ZValueEditField = uieditfield(app.printerGUIFigure, 'numeric');
            app.XValueEditFieldLabel = uilabel(app.printerGUIFigure);
            app.XValueEditFieldLabel.Text = 'X Value';
            app.XValueEditField = uieditfield(app.printerGUIFigure, 'numeric');
            app.YValueEditFieldLabel = uilabel(app.printerGUIFigure);
            app.YValueEditFieldLabel.Text = 'Y Value';
            app.YValueEditField = uieditfield(app.printerGUIFigure, 'numeric');
            app.GOButton = uibutton(app.printerGUIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.printerGUIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.SavedPositionsPanel = uipanel(app.printerGUIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.Set1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.Goto1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto1Button.ButtonPushedFcn = createCallbackFcn(app, @Goto1ButtonPushed, true);
            app.Goto1Button.Text = 'Go to 1';
            app.pos1 = uilabel(app.SavedPositionsPanel);
            app.Set2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.Goto2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto2Button.ButtonPushedFcn = createCallbackFcn(app, @Goto2ButtonPushed, true);
            app.Goto2Button.Text = 'Go to 2';
            app.pos2 = uilabel(app.SavedPositionsPanel);
            app.Set3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.Goto3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto3Button.ButtonPushedFcn = createCallbackFcn(app, @Goto3ButtonPushed, true);
            app.Goto3Button.Text = 'Go to 3';
            app.pos3 = uilabel(app.SavedPositionsPanel);
            app.joint3sliderLabel_2 = uilabel(app.printerGUIFigure);
            app.joint3sliderLabel_2.Text = 'Nozzle';
            app.nozslider = uislider(app.printerGUIFigure);
            app.nozslider.Limits = [0 240];
            app.nozslider.MajorTicks = [0 120 240 360];
            app.nozslider.ValueChangingFcn = createCallbackFcn(app, @nozsliderValueChanging, true);
            app.nozslider.MinorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.nozslider.Step = 120;
            app.NozzleValueEditFieldLabel = uilabel(app.printerGUIFigure);
            app.NozzleValueEditFieldLabel.Text = 'Nozzle Value';
            app.NozzleValueEditField = uieditfield(app.printerGUIFigure, 'numeric');
            app.FilamentPanel = uipanel(app.printerGUIFigure);
            app.FilamentPanel.Title = 'Filament';
            app.Color1ColorPickerLabel = uilabel(app.FilamentPanel);
            app.Color1ColorPickerLabel.Text = 'Color 1';
            app.Color1ColorPicker = uicolorpicker(app.FilamentPanel);
            app.Color1ColorPicker.ValueChangedFcn = createCallbackFcn(app, @Color1ColorPickerValueChanged, true);
            app.Color2ColorPickerLabel = uilabel(app.FilamentPanel);
            app.Color2ColorPickerLabel.Text = 'Color 2';
            app.Color2ColorPicker = uicolorpicker(app.FilamentPanel);
            app.Color2ColorPicker.ValueChangedFcn = createCallbackFcn(app, @Color2ColorPickerValueChanged, true);
            app.Color3ColorPickerLabel = uilabel(app.FilamentPanel);
            app.Color3ColorPickerLabel.Text = 'Color 3';
            app.Color3ColorPicker = uicolorpicker(app.FilamentPanel);
            app.Color3ColorPicker.ValueChangedFcn = createCallbackFcn(app, @Color3ColorPickerValueChanged, true);
            app.ClearPrintBedButton = uibutton(app.FilamentPanel, 'push');
            app.ClearPrintBedButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPrintBedButtonPushed, true);
            app.ClearPrintBedButton.Text = 'Clear Print Bed';
            % Show the figure after all components are created
            app.printerGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = threeDPrinterGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.printerGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.printerGUIFigure)
        end
    end
end