classdef AdeeptArmGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIFigure     matlab.ui.Figure
        J6ValueEditField       matlab.ui.control.NumericEditField
        J6ValueEditFieldLabel  matlab.ui.control.Label
        J5ValueEditField       matlab.ui.control.NumericEditField
        J5ValueEditFieldLabel  matlab.ui.control.Label
        J4ValueEditField       matlab.ui.control.NumericEditField
        J4ValueEditFieldLabel  matlab.ui.control.Label
        joint4slider           matlab.ui.control.Slider
        joint3sliderLabel_4    matlab.ui.control.Label
        joint5slider           matlab.ui.control.Slider
        joint3sliderLabel_3    matlab.ui.control.Label
        joint6slider           matlab.ui.control.Slider
        joint3sliderLabel_2    matlab.ui.control.Label
        SavedPositionsPanel    matlab.ui.container.Panel
        pos3                   matlab.ui.control.Label
        Goto3Button            matlab.ui.control.Button
        Set3Button             matlab.ui.control.Button
        pos2                   matlab.ui.control.Label
        Goto2Button            matlab.ui.control.Button
        Set2Button             matlab.ui.control.Button
        pos1                   matlab.ui.control.Label
        Goto1Button            matlab.ui.control.Button
        Set1Button             matlab.ui.control.Button
        HOMEButton             matlab.ui.control.Button
        GOButton               matlab.ui.control.Button
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        joint2slider           matlab.ui.control.Slider
        joint2sliderLabel      matlab.ui.control.Label
        joint3slider           matlab.ui.control.Slider
        joint3sliderLabel      matlab.ui.control.Label
        joint1slider           matlab.ui.control.Slider
        joint1sliderLabel      matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices
        link6Patch
        link6Vertices
        jointAngles = [0,-90,0,0,0,0];

        %Used saved angles to tell bot to go to that position
        savedAngles1 = [0,0,0,0,0,0];
        savedAngles2 = [0,0,0,0,0,0];
        savedAngles3 = [0,0,0,0,0,0];


    end
    
    methods (Access = private)
        
        function updateArm(app)
            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0,290,-90, app.jointAngles(1));
            A2 = create_A_matrix(270,0,0, app.jointAngles(2));
            A3 = create_A_matrix(70,0,-90, app.jointAngles(3));
            A4 = create_A_matrix(0,302,90, app.jointAngles(4));
            A5 = create_A_matrix(0,0,-90, app.jointAngles(5));
            A6 = create_A_matrix(0,72,0, app.jointAngles(6));
            
            
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = T0_1 * A2;
            T0_3 = T0_2 * A3;
            T0_4 = T0_3 * A4;
            T0_5 = T0_4 * A5;
            T0_6 = T0_5 * A6;
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;
            link5VerticesWRTground = T0_5 * app.link5Vertices;
            link6VerticesWRTground = T0_6 * app.link6Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch, 'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch, 'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch, 'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch, 'Vertices', link4VerticesWRTground(1:3,:)');
            set(app.link5Patch, 'Vertices', link5VerticesWRTground(1:3,:)');
            set(app.link6Patch, 'Vertices', link6VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("link_0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("link_1.stl", app.axes_arm, [211/255 211/255 211/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("link_2.stl", app.axes_arm, [255/255 165/255 0/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("link_3.stl", app.axes_arm, [255/255 255/255 0/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            
            app.link4Patch = makeStlLink("link_4.stl", app.axes_arm, [135/255 206/255 235/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));
            
            app.link5Patch = makeStlLink("link_5.stl", app.axes_arm, [0/255 0/255 128/255]);
            app.link5Vertices = get(app.link5Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));
            
            app.link6Patch = makeStlLink("link_6.stl", app.axes_arm, [255/255 105/255 180/255]);
            app.link6Vertices = get(app.link6Patch, 'Vertices')';
            app.link6Vertices(4,:) = ones(1, size(app.link6Vertices,2));
            app.updateArm();
        end

        % Value changing function: joint1slider
        function joint1sliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            %TODO: Also update the numeric arm
            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;
            app.jointAngles(4) = app.J4ValueEditField.Value;
            app.jointAngles(5) = app.J5ValueEditField.Value;
            app.jointAngles(6) = app.J6ValueEditField.Value;
            
            app.joint1slider.Value = app.jointAngles(1);
            app.joint2slider.Value = app.jointAngles(2);
            app.joint3slider.Value = app.jointAngles(3);
            app.joint4slider.Value = app.jointAngles(4);
            app.joint5slider.Value = app.jointAngles(5);
            app.joint6slider.Value = app.jointAngles(6);

            app.updateArm()
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = -90;
            app.jointAngles(3) = 0;
            app.jointAngles(4) = 0;
            app.jointAngles(5) = 0;
            app.jointAngles(6) = 0;
            app.joint1slider.Value = app.jointAngles(1);
            app.joint2slider.Value = app.jointAngles(2);
            app.joint3slider.Value = app.jointAngles(3);
            app.joint4slider.Value = app.jointAngles(4);
            app.joint5slider.Value = app.jointAngles(5);
            app.joint6slider.Value = app.jointAngles(6);

            app.updateArm()
        end

        % Value changing function: joint2slider
        function joint2sliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            %TODO: Also update the numeric arm
            app.updateArm()
        end

        % Value changing function: joint3slider
        function joint3sliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            %TODO: Also update the numeric arm
            app.updateArm()            
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles;
            app.pos1.Text = sprintf('[%.1f, %.1f, %.1f\n%.1f, %.1f, %.1f]', ...
                app.savedAngles1(1), app.savedAngles1(2), app.savedAngles1(3), ...
                app.savedAngles1(4), app.savedAngles1(5), app.savedAngles1(6));
        end

        % Button pushed function: Goto1Button
        function Goto1ButtonPushed(app, event)
            app.jointAngles = app.savedAngles1;
            app.joint1slider.Value = app.jointAngles(1);
            app.joint2slider.Value = app.jointAngles(2);
            app.joint3slider.Value = app.jointAngles(3);
            app.joint4slider.Value = app.jointAngles(4);
            app.joint5slider.Value = app.jointAngles(5);
            app.joint6slider.Value = app.jointAngles(6);

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointAngles(4);
            app.J5ValueEditField.Value = app.jointAngles(5);
            app.J6ValueEditField.Value = app.jointAngles(6);

            app.updateArm();
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.savedAngles2 = app.jointAngles;
            app.pos2.Text = sprintf('[%.1f, %.1f, %.1f\n%.1f, %.1f, %.1f]', ...
                app.savedAngles2(1), app.savedAngles2(2), app.savedAngles2(3), ...
                app.savedAngles2(4), app.savedAngles2(5), app.savedAngles2(6));
        end

        % Button pushed function: Goto2Button
        function Goto2ButtonPushed(app, event)
            app.jointAngles = app.savedAngles2;
            app.joint1slider.Value = app.jointAngles(1);
            app.joint2slider.Value = app.jointAngles(2);
            app.joint3slider.Value = app.jointAngles(3);
            app.joint4slider.Value = app.jointAngles(4);
            app.joint5slider.Value = app.jointAngles(5);
            app.joint6slider.Value = app.jointAngles(6);

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointAngles(4);
            app.J5ValueEditField.Value = app.jointAngles(5);
            app.J6ValueEditField.Value = app.jointAngles(6);

            app.updateArm();
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.savedAngles3 = app.jointAngles;
            app.pos3.Text = sprintf('[%.1f, %.1f, %.1f\n%.1f, %.1f, %.1f]', ...
                app.savedAngles3(1), app.savedAngles3(2), app.savedAngles3(3), ...
                app.savedAngles3(4), app.savedAngles3(5), app.savedAngles3(6));
        end

        % Button pushed function: Goto3Button
        function Goto3ButtonPushed(app, event)
            app.jointAngles = app.savedAngles2;
            app.joint1slider.Value = app.jointAngles(1);
            app.joint2slider.Value = app.jointAngles(2);
            app.joint3slider.Value = app.jointAngles(3);
            app.joint4slider.Value = app.jointAngles(4);
            app.joint5slider.Value = app.jointAngles(5);
            app.joint6slider.Value = app.jointAngles(6);

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointAngles(4);
            app.J5ValueEditField.Value = app.jointAngles(5);
            app.J6ValueEditField.Value = app.jointAngles(6);

            app.updateArm();
        end

        % Value changing function: joint6slider
        function joint6sliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(4) = changingValue;
            %TODO: Also update the numeric arm
            app.updateArm()             
        end

        % Value changing function: joint5slider
        function joint5sliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(5) = changingValue;
            %TODO: Also update the numeric arm
            app.updateArm() 
        end

        % Value changing function: joint4slider
        function joint4sliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(6) = changingValue;
            %TODO: Also update the numeric arm
            app.updateArm() 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.AdeeptArmGUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.joint1sliderLabel = uilabel(app.AdeeptArmGUIFigure);
            app.joint1sliderLabel.Text = 'J1';
            app.joint1slider = uislider(app.AdeeptArmGUIFigure);
            app.joint1slider.Limits = [-90 90];
            app.joint1slider.ValueChangingFcn = createCallbackFcn(app, @joint1sliderValueChanging, true);
            app.joint1slider.Step = 30;
            app.joint3sliderLabel = uilabel(app.AdeeptArmGUIFigure);
            app.joint3sliderLabel.Text = 'J3';
            app.joint3slider = uislider(app.AdeeptArmGUIFigure);
            app.joint3slider.Limits = [-10 50];
            app.joint3slider.ValueChangingFcn = createCallbackFcn(app, @joint3sliderValueChanging, true);
            app.joint3slider.Step = 30;
            app.joint2sliderLabel = uilabel(app.AdeeptArmGUIFigure);
            app.joint2sliderLabel.Text = 'J2';
            app.joint2slider = uislider(app.AdeeptArmGUIFigure);
            app.joint2slider.Limits = [-10 40];
            app.joint2slider.ValueChangingFcn = createCallbackFcn(app, @joint2sliderValueChanging, true);
            app.joint2slider.Step = 30;
            app.J1ValueEditFieldLabel = uilabel(app.AdeeptArmGUIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.AdeeptArmGUIFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.AdeeptArmGUIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.AdeeptArmGUIFigure, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.AdeeptArmGUIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.AdeeptArmGUIFigure, 'numeric');
            app.GOButton = uibutton(app.AdeeptArmGUIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.AdeeptArmGUIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.SavedPositionsPanel = uipanel(app.AdeeptArmGUIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.Set1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.Goto1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto1Button.ButtonPushedFcn = createCallbackFcn(app, @Goto1ButtonPushed, true);
            app.Goto1Button.Text = 'Go to 1';
            app.pos1 = uilabel(app.SavedPositionsPanel);
            app.Set2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.Goto2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto2Button.ButtonPushedFcn = createCallbackFcn(app, @Goto2ButtonPushed, true);
            app.Goto2Button.Text = 'Go to 2';
            app.pos2 = uilabel(app.SavedPositionsPanel);
            app.Set3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.Goto3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto3Button.ButtonPushedFcn = createCallbackFcn(app, @Goto3ButtonPushed, true);
            app.Goto3Button.Text = 'Go to 3';
            app.pos3 = uilabel(app.SavedPositionsPanel);
            app.joint3sliderLabel_2 = uilabel(app.AdeeptArmGUIFigure);
            app.joint3sliderLabel_2.Text = 'J4';
            app.joint6slider = uislider(app.AdeeptArmGUIFigure);
            app.joint6slider.Limits = [-90 90];
            app.joint6slider.ValueChangingFcn = createCallbackFcn(app, @joint6sliderValueChanging, true);
            app.joint6slider.Step = 30;
            app.joint3sliderLabel_3 = uilabel(app.AdeeptArmGUIFigure);
            app.joint3sliderLabel_3.Text = 'J5';
            app.joint5slider = uislider(app.AdeeptArmGUIFigure);
            app.joint5slider.Limits = [-90 90];
            app.joint5slider.ValueChangingFcn = createCallbackFcn(app, @joint5sliderValueChanging, true);
            app.joint5slider.Step = 30;
            app.joint3sliderLabel_4 = uilabel(app.AdeeptArmGUIFigure);
            app.joint3sliderLabel_4.Text = 'J6';
            app.joint4slider = uislider(app.AdeeptArmGUIFigure);
            app.joint4slider.Limits = [-180 180];
            app.joint4slider.ValueChangingFcn = createCallbackFcn(app, @joint4sliderValueChanging, true);
            app.joint4slider.Step = 30;
            app.J4ValueEditFieldLabel = uilabel(app.AdeeptArmGUIFigure);
            app.J4ValueEditFieldLabel.Text = 'J4 Value';
            app.J4ValueEditField = uieditfield(app.AdeeptArmGUIFigure, 'numeric');
            app.J5ValueEditFieldLabel = uilabel(app.AdeeptArmGUIFigure);
            app.J5ValueEditFieldLabel.Text = 'J5 Value';
            app.J5ValueEditField = uieditfield(app.AdeeptArmGUIFigure, 'numeric');
            app.J6ValueEditFieldLabel = uilabel(app.AdeeptArmGUIFigure);
            app.J6ValueEditFieldLabel.Text = 'J6 Value';
            app.J6ValueEditField = uieditfield(app.AdeeptArmGUIFigure, 'numeric');
            % Show the figure after all components are created
            app.AdeeptArmGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIFigure)
        end
    end
end