classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Flash                     matlab.ui.control.Button
        Period                    matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel    matlab.ui.control.Label
        NumFlashes                matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel  matlab.ui.control.Label
        ServerResponse            matlab.ui.control.Label
        LEDOFF                    matlab.ui.control.Button
        LEDON                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Robot % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            piIP = "joeyjack-pi400"; 

            app.Robot = LEDexam(piIP);
   
            try
                app.Robot = LEDexam(piIP);
                disp("Connected to arduino on " + piIP);
            catch ME
                errordlg("Failed to connect on " + piIP + ": " + ME.message, "Connection Error");
            end
        end

        % Button pushed function: LEDON
        function LEDONPushed(app, event)
            app.Robot.LEDOnOff("on");
        end

        % Button pushed function: LEDOFF
        function LEDOFFPushed(app, event)
            app.Robot.LEDOnOff("off");
        end

        % Button pushed function: Flash
        function FlashPushed(app, event)
            numFlash = app.NumFlashes.Value;
            timeDelay = app.Period.Value;
            app.Robot.Flash(numFlash, timeDelay);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDON = uibutton(app.UIFigure, 'push');
            app.LEDON.ButtonPushedFcn = createCallbackFcn(app, @LEDONPushed, true);
            app.LEDON.Text = 'LED ON';
            app.LEDOFF = uibutton(app.UIFigure, 'push');
            app.LEDOFF.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFPushed, true);
            app.LEDOFF.Text = 'LED OFF';
            app.ServerResponse = uilabel(app.UIFigure);
            app.ServerResponse.Text = 'Server Response';
            app.NumFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes:';
            app.NumFlashes = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms):';
            app.Period = uieditfield(app.UIFigure, 'numeric');
            app.Flash = uibutton(app.UIFigure, 'push');
            app.Flash.ButtonPushedFcn = createCallbackFcn(app, @FlashPushed, true);
            app.Flash.Text = 'Flash';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end