classdef GoldGuesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        potOgold             matlab.ui.control.Image
        leprechaun           matlab.ui.control.Image
        PercentCorrectValue  matlab.ui.control.Label
        SuccessValue         matlab.ui.control.Label
        AttemptsValue        matlab.ui.control.Label
        PercentCorrect       matlab.ui.control.Label
        Success              matlab.ui.control.Label
        Attempts             matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
    end

    
    properties (Access = private)
        LeprechaunPositions
        GoldPositions
        LeprechaunImageSources
        Attemptss = 0
        Successes = 0
    end
    
    methods (Access = private)

        function makeAGuess (app, leprechaunIndex)
            app.Attemptss = app.Attemptss + 1;
            app.leprechaun.Position = app.LeprechaunPositions(leprechaunIndex,:)
            goldIndex = randi([1,3]);
            app.potOgold.Position = app.GoldPositions(goldIndex,:);

            if goldIndex == leprechaunIndex
                app.leprechaun.ImageSource = app.LeprechaunImageSources(1)
                app.Successes = app.Successes + 1;
            else
                app.leprechaun.ImageSource = app.LeprechaunImageSources(2)
            end

            app.AttemptsValue.Text = string(app.Attemptss);
            app.SuccessValue.Text = string(app.Successes);
            app.LabelPercentValue.Text = num2str(app.Successes/app.Attemptss * 100, 0)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.LeprechaunImageSources = ["./happyLeprechaun.png","./SadLeprechaun.png"]
            app.LeprechaunPositions(1,:) = [102,251,100,100];
            app.LeprechaunPositions(2,:) = [271,242,100,100];
            app.LeprechaunPositions(3,:) = [451,242,100,100];

            app.GoldPositions(1,:) = [102,62,100,100];
            app.GoldPositions(2,:) = [271,62,100,100];
            app.GoldPositions(3,:) = [451,62,100,100];

        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            % app.leprechaun.ImageSource = app.LeprechaunImageSources(1);
            % app.leprechaun.Position = app.LeprechaunPositions(1,:);
            app.makeAGuess(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % app.leprechaun.ImageSource = app.LeprechaunImageSources(1);
            % app.leprechaun.Position = app.LeprechaunPositions(2,:);
            app.makeAGuess(2)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % app.leprechaun.ImageSource = app.LeprechaunImageSources(2);
            % app.leprechaun.Position = app.LeprechaunPositions(3,:);
            app.makeAGuess(3)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Attempts = uilabel(app.UIFigure);
            app.Attempts.Text = 'Attempts:';
            app.Success = uilabel(app.UIFigure);
            app.Success.Text = 'Success: ';
            app.PercentCorrect = uilabel(app.UIFigure);
            app.PercentCorrect.Text = 'Percent Correct:';
            app.AttemptsValue = uilabel(app.UIFigure);
            app.AttemptsValue.Text = '0';
            app.SuccessValue = uilabel(app.UIFigure);
            app.SuccessValue.Text = '0';
            app.PercentCorrectValue = uilabel(app.UIFigure);
            app.PercentCorrectValue.Text = '%';
            app.leprechaun = uiimage(app.UIFigure);
            app.leprechaun.ImageSource = 'SadLeprechaun.png';
            app.potOgold = uiimage(app.UIFigure);
            app.potOgold.ImageSource = 'potoGold.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoldGuesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end