classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SendLEDSButton     matlab.ui.control.Button
        GreenSwitch        matlab.ui.control.ToggleSwitch
        GreenSwitchLabel   matlab.ui.control.Label
        YellowSwitch       matlab.ui.control.ToggleSwitch
        YellowSwitchLabel  matlab.ui.control.Label
        RedSwitch          matlab.ui.control.ToggleSwitch
        RedSwitchLabel     matlab.ui.control.Label
        CounterLabel       matlab.ui.control.Label
        GreenOffButton     matlab.ui.control.Button
        GreenOnButton      matlab.ui.control.Button
        YellowOffButton    matlab.ui.control.Button
        YellowOnButton     matlab.ui.control.Button
        RedOffButton       matlab.ui.control.Button
        RedOnButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient
        counter = 0
    end
    
    methods (Access = private)
        
        function myCallback(app,topic,message)
            fprintf("Recieved: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type

            if messageType == "button"
                app.counter = app.counter +1;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter)
            end

            if messageType == "reset"
                app.counter = 0;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter)
            end
        end

        function sendMessage(app, messageType, messagePayload)
            messageStuct.type = messageType;
            messageStuct.payload = messagePayload
            messageJson = jsonencode(messageStuct)

            write(app.mqClient, "me435/schmitjx/to_pi", messageJson)
            fprintf("Sent: %s\n", messageJson)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/schmitjx/#", "Callback", @myCallback)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedOnButton = uibutton(app.UIFigure, 'push');
            app.RedOnButton.Text = 'Red On';
            app.RedOffButton = uibutton(app.UIFigure, 'push');
            app.RedOffButton.Text = 'Red Off';
            app.YellowOnButton = uibutton(app.UIFigure, 'push');
            app.YellowOnButton.Text = 'Yellow On';
            app.YellowOffButton = uibutton(app.UIFigure, 'push');
            app.YellowOffButton.Text = 'Yellow Off';
            app.GreenOnButton = uibutton(app.UIFigure, 'push');
            app.GreenOnButton.Text = 'Green On';
            app.GreenOffButton = uibutton(app.UIFigure, 'push');
            app.GreenOffButton.Text = 'Green Off';
            app.CounterLabel = uilabel(app.UIFigure);
            app.CounterLabel.Text = 'Counter';
            app.RedSwitchLabel = uilabel(app.UIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.UIFigure, 'toggle');
            app.YellowSwitchLabel = uilabel(app.UIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.UIFigure, 'toggle');
            app.GreenSwitchLabel = uilabel(app.UIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SendLEDSButton = uibutton(app.UIFigure, 'push');
            app.SendLEDSButton.Text = 'Send LEDS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end