classdef exam3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BothReverseButton   matlab.ui.control.Button
        BothForwardButton   matlab.ui.control.Button
        RightReverseButton  matlab.ui.control.Button
        LeftReverseButton   matlab.ui.control.Button
        RightForwardButton  matlab.ui.control.Button
        Distance0cmLabel    matlab.ui.control.Label
        LeftForwardButton   matlab.ui.control.Button
        RightSlider         matlab.ui.control.Slider
        RightSliderLabel    matlab.ui.control.Label
        LeftSlider          matlab.ui.control.Slider
        LeftSliderLabel     matlab.ui.control.Label
        TimeSlider          matlab.ui.control.Slider
        TimeSliderLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient % Description
    end

    methods (Access = private)

        function myCallback(app,topic,message)
            fprintf("Received: %s\n", message);
            try
                messageStruct = jsondecode(message);
                if isfield(messageStruct, 'type') && strcmp(messageStruct.type, 'ultra')
                    % Round distance to integer for GUI display as requested
                    distInt = round(messageStruct.payload);
                    app.DistanceLabel.Text = sprintf('Distance = %d cm', distInt);
                end
            catch
                fprintf("Failed to parse incoming message.\n");
            end
        end
        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);
            write(app.mqClient, "me435/schmitjx/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.UIFigure.Name = 'Exam 3 MQTT';
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/schmitjx/#", "Callback", @app.myCallback)
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            t = app.TimeSlider.Value;
            pwm = app.LeftSlider.Value;
            app.sendMessage("left", [1, t, pwm]);
        end

        % Button pushed function: LeftReverseButton
        function LeftReverseButtonPushed(app, event)
            t = app.TimeSlider.Value;
            pwm = app.LeftSlider.Value;
            app.sendMessage("left", [0, t, pwm]);
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            t = app.TimeSlider.Value;
            pwm = app.RightSlider.Value;
            app.sendMessage("right", [1, t, pwm]);
        end

        % Button pushed function: RightReverseButton
        function RightReverseButtonPushed(app, event)
            t = app.TimeSlider.Value;
            pwm = app.RightSlider.Value;
            app.sendMessage("right", [0, t, pwm]);
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
            t = app.TimeSlider.Value;
            left_pwm = app.LeftSlider.Value;
            right_pwm = app.RightSlider.Value;
            app.sendMessage("both", [1, t, left_pwm, right_pwm]);
        end

        % Button pushed function: BothReverseButton
        function BothReverseButtonPushed(app, event)
            t = app.TimeSlider.Value;
            left_pwm = app.LeftSlider.Value;
            right_pwm = app.RightSlider.Value;
            app.sendMessage("both", [0, t, left_pwm, right_pwm]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0 4];
            app.TimeSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.TimeSlider.Step = 0.5;
            app.TimeSlider.Value = 1;
            app.LeftSliderLabel = uilabel(app.UIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.UIFigure);
            app.LeftSlider.Value = 40;
            app.RightSliderLabel = uilabel(app.UIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.UIFigure);
            app.RightSlider.Value = 40;
            app.LeftForwardButton = uibutton(app.UIFigure, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.Distance0cmLabel = uilabel(app.UIFigure);
            app.Distance0cmLabel.Text = 'Distance = 0 cm';
            app.RightForwardButton = uibutton(app.UIFigure, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.LeftReverseButton = uibutton(app.UIFigure, 'push');
            app.LeftReverseButton.ButtonPushedFcn = createCallbackFcn(app, @LeftReverseButtonPushed, true);
            app.LeftReverseButton.Text = 'Left Reverse';
            app.RightReverseButton = uibutton(app.UIFigure, 'push');
            app.RightReverseButton.ButtonPushedFcn = createCallbackFcn(app, @RightReverseButtonPushed, true);
            app.RightReverseButton.Text = 'Right Reverse';
            app.BothForwardButton = uibutton(app.UIFigure, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.BothReverseButton = uibutton(app.UIFigure, 'push');
            app.BothReverseButton.ButtonPushedFcn = createCallbackFcn(app, @BothReverseButtonPushed, true);
            app.BothReverseButton.Text = 'Both Reverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end