classdef hacky < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SendButton             matlab.ui.control.Button
        LastMessageLabel       matlab.ui.control.Label
        PayloadEditField       matlab.ui.control.EditField
        PayloadEditFieldLabel  matlab.ui.control.Label
        TypeEditField          matlab.ui.control.EditField
        TypeEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        mqClient
    end
    
    methods (Access = private)
        
        function myCallback(app,topic,message)
            fprintf("Recieved: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type

            messagePayload = messageStruct.payload

            app.LastMessageLabel.Text = messagePayload;
        end

        function sendMessage(app, messageType, messagePayload)
            messageStuct.type = messageType;
            messageStuct.payload = messagePayload
            messageJson = jsonencode(messageStuct)

            write(app.mqClient, "chat", messageJson)
            fprintf("Sent: %s\n", messageJson)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");

            app.mqClient.subscribe("chat","Callback",@app.myCallback);

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.sendMessage(app.TypeEditField.Value, app.PayloadEditField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TypeEditFieldLabel = uilabel(app.UIFigure);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.UIFigure, 'text');
            app.TypeEditField.Value = 'Chat';
            app.PayloadEditFieldLabel = uilabel(app.UIFigure);
            app.PayloadEditFieldLabel.Text = 'Payload';
            app.PayloadEditField = uieditfield(app.UIFigure, 'text');
            app.LastMessageLabel = uilabel(app.UIFigure);
            app.LastMessageLabel.Text = 'Last Message Label';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hacky

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end